classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel_3                        matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        Label                          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        CertificateVerificationLabel   matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        ClearAllButton                 matlab.ui.control.Button
        ReadyLabel                     matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        Panel_5                        matlab.ui.container.Panel
        SelectExpectedWaterMarkButton  matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        KPRIET2026001Button            matlab.ui.control.Button
        VerifyCertificateLabel         matlab.ui.control.Label
        SelectCertificateLabel_2       matlab.ui.control.Label
        VerificationStatusLabel        matlab.ui.control.Label
        SimilarityLabel                matlab.ui.control.Label
        ExtractedWatermarkLabel        matlab.ui.control.Label
        ExpectedWatermarkLabel         matlab.ui.control.Label
        VerifyCertificateButton        matlab.ui.control.Button
        SelectCertificateButton_2      matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        Panel_4                        matlab.ui.container.Panel
        WatermarkEmbeddingLabel        matlab.ui.control.Label
        SaveWatermarkedCertificateButton  matlab.ui.control.Button
        EmbedWatermarkButton           matlab.ui.control.Button
        Label2                         matlab.ui.control.Label
        EmbedWatermarkLabel            matlab.ui.control.Label
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        SelectmethodLabel              matlab.ui.control.Label
        IDEditField                    matlab.ui.control.EditField
        CertificateIDLabel             matlab.ui.control.Label
        SelectCertificateButton        matlab.ui.control.Button
        SelectCertificateLabel         matlab.ui.control.Label
        UIAxes2_2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SelectedImage
        SelectedImagePath
        WatermarkedImage 
        VerificationImage 
        ExpectedWatermark=''
        ExpectedMethod=''
        ExpectedWatermarkFile=''

        % Preprocessing
        PreprocessedGray
        NormalizedImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectCertificateButton
        function SelectCertificateButtonPushed(app, event)
    [file, path] = uigetfile( ...
        {'*.png;*.jpg;*.jpeg', ...
        'Image Files (*.png, *.jpg, *.jpeg)'}, ...
        'Select Certificate');

    if isequal(file,0)
        return;
    end

    img = imread(fullfile(path,file));

    app.SelectedImage = img;
    app.SelectedImagePath = fullfile(path,file);

    % Display original certificate
    imshow(img, 'Parent', app.UIAxes2_2);

    app.StatusLabel.Text = 'Certificate selected and preprocessed successfully';

        end

        % Button pushed function: EmbedWatermarkButton
        function EmbedWatermarkButtonPushed(app, event)
% Button pushed function: EmbedWatermarkButton


%% Check whether certificate is selected

if isempty(app.SelectedImage)
    uialert(app.UIFigure, ...
        'Please select a certificate first.', ...
        'No Certificate');
    return;
end


%% Get Certificate ID

watermark = app.IDEditField.Value;

if isempty(watermark)
    uialert(app.UIFigure, ...
        'Please enter a Certificate ID.', ...
        'No Watermark');
    return;
end


%% Get selected method

method = app.MethodDropDown.Value;


%% Get selected certificate

img = app.SelectedImage;


%% =====================================================
%          GENERATE SHA-256 HASH OF CERTIFICATE ID
% ======================================================

% Convert Certificate ID to UTF-8 bytes
watermarkText = char(watermark);

messageBytes = uint8(unicode2native(watermarkText, 'UTF-8'));

% Create SHA-256 hash object
md = java.security.MessageDigest.getInstance('SHA-256');

% Generate SHA-256 hash
hashBytes = md.digest(messageBytes);

% Preserve the original 8-bit patterns
hashBytes = typecast(int8(hashBytes(:)), 'uint8');

% Convert to a row vector
hashBytes = hashBytes(:).';

% SHA-256 produces exactly 32 bytes = 256 bits
binaryWatermark = dec2bin(hashBytes, 8)';

binaryWatermark = binaryWatermark(:)' - '0';

watermarkLength = length(binaryWatermark);


%% =====================================================
%                    LSB METHOD
% ======================================================

if strcmp(method, 'LSB')

    % Convert to grayscale
    if size(img,3) == 3
        grayImg = rgb2gray(img);
    else
        grayImg = img;
    end

    % Convert image to pixels
    pixels = grayImg(:);

    % Fixed random positions
    rng(100);

    % Check capacity
    if watermarkLength > length(pixels)

        uialert(app.UIFigure, ...
            'Certificate image is too small for the SHA-256 watermark.', ...
            'Insufficient Capacity');

        return;
    end

    % Select pixel positions
    positions = randperm(length(pixels), watermarkLength);

    % Embed watermark into LSB
    for i = 1:watermarkLength

        pixels(positions(i)) = ...
            bitset(pixels(positions(i)), ...
            1, binaryWatermark(i));

    end

    % Create watermarked image
    watermarkedImg = reshape(pixels, size(grayImg));


%% =====================================================
%                    DCT METHOD
% ======================================================

elseif strcmp(method, 'DCT')

    blockSize = 8;

    alpha = 40;

    rng(100);

    % Convert to grayscale
    if size(img,3) == 3
        img = rgb2gray(img);
    end

    % Convert image to double
    img = double(img);

    % Make dimensions divisible by 8
    [rows, cols] = size(img);

    newRows = floor(rows / blockSize) * blockSize;

    newCols = floor(cols / blockSize) * blockSize;

    img = img(1:newRows, 1:newCols);

    [rows, cols] = size(img);


    % Number of 8x8 blocks
    numBlocks = (rows / blockSize) * ...
                (cols / blockSize);

    % Check capacity
    if watermarkLength > numBlocks

        uialert(app.UIFigure, ...
            'Certificate image is too small for the SHA-256 watermark.', ...
            'Insufficient Capacity');

        return;
    end


    % Select blocks
    selectedBlocks = ...
        randperm(numBlocks, watermarkLength);


    % DCT coefficient positions
    p1 = [3 4];
    p2 = [4 3];

    watermarkedImg = img;

    blocksPerRow = cols / blockSize;


    % Embed watermark
    for k = 1:watermarkLength

        blockNumber = selectedBlocks(k);

        blockRow = ceil(blockNumber / blocksPerRow);

        blockCol = ...
            mod(blockNumber - 1, blocksPerRow) + 1;

        r1 = (blockRow-1)*blockSize + 1;

        r2 = blockRow*blockSize;

        c1 = (blockCol-1)*blockSize + 1;

        c2 = blockCol*blockSize;


        % Extract block
        block = watermarkedImg(r1:r2, c1:c2);


        % DCT
        D = dct2(block);


        % Get coefficients
        cA = D(p1(1),p1(2));

        cB = D(p2(1),p2(2));


        % Embed bit
        if binaryWatermark(k) == 1

            difference = cA - cB;

            if difference < alpha

                adjustment = ...
                    (alpha - difference) / 2;

                cA = cA + adjustment;

                cB = cB - adjustment;

            end

        else

            difference = cB - cA;

            if difference < alpha

                adjustment = ...
                    (alpha - difference) / 2;

                cB = cB + adjustment;

                cA = cA - adjustment;

            end

        end


        % Put coefficients back
        D(p1(1),p1(2)) = cA;

        D(p2(1),p2(2)) = cB;


        % Inverse DCT
        watermarkedBlock = idct2(D);


        % Replace block
        watermarkedImg(r1:r2,c1:c2) = ...
            watermarkedBlock;

    end


    % Convert to uint8
    watermarkedImg = uint8( ...
        min(max(round(watermarkedImg),0),255));


else

    uialert(app.UIFigure, ...
        'Please select LSB or DCT.', ...
        'Invalid Method');

    return;

end


%% =====================================================
%              STORE WATERMARK INFORMATION
% ======================================================

app.WatermarkedImage = watermarkedImg;


%% Display watermarked certificate

imshow(watermarkedImg, 'Parent', app.UIAxes2_2);


%% Update status

app.StatusLabel.Text = ...
    [method ' SHA-256 watermark embedded successfully'];


%% Show message

uialert(app.UIFigure, ...
    [method ' SHA-256 watermark embedded successfully. ' ...
     'Click Save Watermarked Certificate.'], ...
    'Success');

        end

        % Button pushed function: SaveWatermarkedCertificateButton
        function SaveWatermarkedCertificateButtonPushed(app, event)
            % Button pushed function: SaveWatermarkedCertificateButton
            if isempty(app.WatermarkedImage)

                uialert(app.UIFigure, ...
                    'Please embed a watermark first.', ...
                    'No Watermarked Image');

                return;
            end


            %% Get Certificate ID

            certificateID = strtrim(app.IDEditField.Value);

            if isempty(certificateID)

                uialert(app.UIFigure, ...
                    'Please enter Certificate ID.', ...
                    'No Certificate ID');

                return;
            end


            %% Get method

            method = app.MethodDropDown.Value;


            %% Select location to save certificate

            [file, path] = uiputfile( ...
                {'.png','PNG Image (.png)'}, ...
                'Save Watermarked Certificate');

            if isequal(file,0)
                return;
            end


            %% Save watermarked certificate

            certificatePath = fullfile(path,file);

            imwrite(app.WatermarkedImage, certificatePath);


            %% Create expected watermark information

            expectedWatermark = certificateID;
            expectedMethod = method;


            %% Save expected watermark

            [~,name,~] = fileparts(file);

            watermarkFile = fullfile( ...
                path, ...
                [name '_ExpectedWatermark.mat']);


            save(watermarkFile, ...
                'expectedWatermark', ...
                'expectedMethod');


            %% Update status

            app.StatusLabel.Text = ...
                'Certificate and expected watermark saved successfully';


            uialert(app.UIFigure, ...
                sprintf(['Certificate saved successfully.\n\n' ...
                'Expected watermark file also saved:\n%s'], ...
                [name '_ExpectedWatermark.mat']), ...
                'Save Successful');
       
        end

        % Callback function: SelectCertificateButton_2, UIAxes2
        function SelectCertificateButton_2Pushed(app, event)
            % Select certificate for verification
            [file, path] = uigetfile( ...
                {'*.png;*.jpg;*.jpeg', ...
                'Image Files (*.png, *.jpg, *.jpeg)'}, ...
                'Select Certificate for Verification');

            % If user cancels
            if isequal(file, 0)
                return;
            end

            % Read certificate
            img = imread(fullfile(path, file));

            % Store verification image
            app.VerificationImage = img;

            % Display certificate in verification preview
            imshow(img, 'Parent', app.UIAxes2);

            % Update status
            app.StatusLabel.Text = 'Certificate selected for verification';
           
        end

        % Button pushed function: VerifyCertificateButton
        function VerifyCertificateButtonPushed(app, event)


   % =========================================================
% VERIFY CERTIFICATE
% =========================================================

%% CHECK CERTIFICATE

if isempty(app.VerificationImage)

    uialert(app.UIFigure, ...
        'Please select a certificate first.', ...
        'Certificate Required');

    return;
end


%% CHECK EXPECTED WATERMARK

if isempty(app.ExpectedWatermark)

    uialert(app.UIFigure, ...
        'Please select the expected watermark first.', ...
        'Expected Watermark Required');

    return;
end


%% GET EXPECTED ID AND METHOD

expectedWatermark = strtrim(char(string(app.ExpectedWatermark)));

method = char(string(app.ExpectedMethod));


%% GENERATE EXPECTED SHA-256 HASH

messageBytes = uint8(unicode2native(expectedWatermark, 'UTF-8'));

mdExpected = java.security.MessageDigest.getInstance('SHA-256');

expectedHashJava = mdExpected.digest(messageBytes);

% Preserve the original 8-bit patterns
expectedHashBytes = typecast(int8(expectedHashJava(:)), 'uint8');

expectedHashBytes = expectedHashBytes(:).';

% Convert expected hash to hexadecimal
expectedHashHex = lower( ...
    reshape(dec2hex(expectedHashBytes, 2).', 1, []));


%% PREPARE CERTIFICATE IMAGE

img = app.VerificationImage;

if size(img, 3) == 3
    img = rgb2gray(img);
end


%% SHA-256 WATERMARK LENGTH

totalBits = 256;


%% EXTRACT WATERMARK

if strcmpi(method, 'LSB')

    pixels = img(:);

    if totalBits > numel(pixels)

        uialert(app.UIFigure, ...
            'Certificate is too small for SHA-256 verification.', ...
            'Verification Error');

        return;
    end

    % Must match the LSB embedding positions
    rng(100);

    positions = randperm(numel(pixels), totalBits);

    extractedBits = double(bitget(pixels(positions), 1));


elseif strcmpi(method, 'DCT')

    img = double(img);

    blockSize = 8;

    rows = floor(size(img, 1) / blockSize) * blockSize;
    cols = floor(size(img, 2) / blockSize) * blockSize;

    img = img(1:rows, 1:cols);

    blockRows = rows / blockSize;
    blockCols = cols / blockSize;

    totalBlocks = blockRows * blockCols;

    if totalBits > totalBlocks

        uialert(app.UIFigure, ...
            'Certificate is too small for SHA-256 verification.', ...
            'Verification Error');

        return;
    end

    % Must match the DCT embedding block selection
    rng(100);

    blockPositions = randperm(totalBlocks, totalBits);

    extractedBits = zeros(1, totalBits);

    for i = 1:totalBits

        blockIndex = blockPositions(i);

        blockRow = floor((blockIndex - 1) / blockCols);
        blockCol = mod(blockIndex - 1, blockCols);

        r = blockRow * blockSize + 1;
        c = blockCol * blockSize + 1;

        block = img(r:r+7, c:c+7);

        dctBlock = dct2(block);

        coefficient1 = dctBlock(3, 4);
        coefficient2 = dctBlock(4, 3);

        if coefficient1 > coefficient2
            extractedBits(i) = 1;
        else
            extractedBits(i) = 0;
        end

    end

else

    uialert(app.UIFigure, ...
        'Invalid watermarking method.', ...
        'Verification Error');

    return;

end


%% CONVERT EXTRACTED BITS TO BYTES

extractedBits = extractedBits(:).';

extractedHashBytes = zeros(1, 32, 'uint8');

for i = 1:32

    byteBits = extractedBits((i-1)*8 + 1 : i*8);

    decimalValue = bin2dec(char(byteBits + '0'));

    extractedHashBytes(i) = uint8(decimalValue);

end


%% CONVERT EXTRACTED HASH TO HEXADECIMAL

extractedHashHex =  ...
    reshape(dec2hex(extractedHashBytes, 2).', 1, []);


%% CALCULATE BIT-LEVEL SIMILARITY

expectedBits = dec2bin(expectedHashBytes, 8).';

expectedBits = expectedBits(:).' - '0';

matchingBits = sum(extractedBits == expectedBits);

similarity = (matchingBits / totalBits) * 100;


%% DISPLAY RESULTS

% Display the original Certificate ID only when the hashes match
if strcmpi(extractedHashHex, expectedHashHex)

    app.Button.Text = expectedWatermark;

else

    app.Button.Text = extractedHashHex;

end

% Display bit-level similarity
app.Button_2.Text = sprintf('%.2f%%', similarity);

%% FINAL AUTHENTICATION DECISION

% Compare extracted hash with the expected hash
hashMatches = strcmpi(extractedHashHex, expectedHashHex);

% Display verification result
if hashMatches && similarity == 100

    app.Button_3.Text = 'AUTHENTIC CERTIFICATE';

    app.StatusLabel.Text = ...
        'Certificate verified successfully';

else

    app.Button_3.Text = 'TAMPERED CERTIFICATE';

    app.StatusLabel.Text = ...
        'Certificate verification failed';

end
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: SelectExpectedWaterMarkButton
        function SelectExpectedWaterMarkButtonPushed(app, event)
                [file, path] = uigetfile( ...
                    '*.mat', ...
                    'Select Expected Watermark File');

                if isequal(file, 0)
                    return;
                end

                fullPath = fullfile(path, file);

                data = load(fullPath);


                % Check whether required data exists
                if ~isfield(data, 'expectedWatermark') || ...
                        ~isfield(data, 'expectedMethod')

                    uialert(app.UIFigure, ...
                        'Invalid expected watermark file.', ...
                        'Invalid File');

                    return;

                end


                % Store expected watermark
                app.ExpectedWatermark = ...
                    char(string(data.expectedWatermark));


                % Store method
                app.ExpectedMethod = ...
                    char(string(data.expectedMethod));


                % Store file path
                app.ExpectedWatermarkFile = fullPath;

                % Display selected file name
                app.ExpectedWatermarkLabel.Text = 'Expected Watermark';

                app.KPRIET2026001Button.Text=app.ExpectedWatermark;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Digital Certificate Authentication System';
            app.Panel_4 = uipanel(app.Panel);
            app.UIAxes2_2 = uiaxes(app.Panel_4);
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.SelectCertificateLabel = uilabel(app.Panel_4);
            app.SelectCertificateLabel.Text = '1. Select Certificate';
            app.SelectCertificateButton = uibutton(app.Panel_4, 'push');
            app.SelectCertificateButton.ButtonPushedFcn = createCallbackFcn(app, @SelectCertificateButtonPushed, true);
            app.SelectCertificateButton.Text = 'Select Certificate';
            app.CertificateIDLabel = uilabel(app.Panel_4);
            app.CertificateIDLabel.Text = '2. Certificate ID';
            app.IDEditField = uieditfield(app.Panel_4, 'text');
            app.IDEditField.Value = 'KPRIET2026-001';
            app.SelectmethodLabel = uilabel(app.Panel_4);
            app.SelectmethodLabel.Text = '3. Select method';
            app.MethodDropDownLabel = uilabel(app.Panel_4);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.Panel_4);
            app.MethodDropDown.Items = {'DCT', 'LSB'};
            app.MethodDropDown.Value = 'LSB';
            app.EmbedWatermarkLabel = uilabel(app.Panel_4);
            app.EmbedWatermarkLabel.Text = '4. Embed Watermark';
            app.Label2 = uilabel(app.Panel_4);
            app.Label2.Text = '5. Save Watermarked certificate';
            app.EmbedWatermarkButton = uibutton(app.Panel_4, 'push');
            app.EmbedWatermarkButton.ButtonPushedFcn = createCallbackFcn(app, @EmbedWatermarkButtonPushed, true);
            app.EmbedWatermarkButton.Text = 'Embed Watermark';
            app.SaveWatermarkedCertificateButton = uibutton(app.Panel_4, 'push');
            app.SaveWatermarkedCertificateButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWatermarkedCertificateButtonPushed, true);
            app.SaveWatermarkedCertificateButton.Text = 'Save Watermarked certificate';
            app.WatermarkEmbeddingLabel = uilabel(app.Panel_4);
            app.WatermarkEmbeddingLabel.Text = '     Watermark Embedding';
            app.Panel_5 = uipanel(app.Panel);
            app.Panel_5.Tooltip = {''};
            app.UIAxes2 = uiaxes(app.Panel_5);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @SelectCertificateButton_2Pushed, true);
            app.SelectCertificateButton_2 = uibutton(app.Panel_5, 'push');
            app.SelectCertificateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectCertificateButton_2Pushed, true);
            app.SelectCertificateButton_2.Text = 'Select Certificate';
            app.VerifyCertificateButton = uibutton(app.Panel_5, 'push');
            app.VerifyCertificateButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyCertificateButtonPushed, true);
            app.VerifyCertificateButton.Text = 'Verify Certificate';
            app.ExpectedWatermarkLabel = uilabel(app.Panel_5);
            app.ExpectedWatermarkLabel.Text = 'Expected Watermark';
            app.ExtractedWatermarkLabel = uilabel(app.Panel_5);
            app.ExtractedWatermarkLabel.Text = 'Extracted Watermark';
            app.SimilarityLabel = uilabel(app.Panel_5);
            app.SimilarityLabel.Text = 'Similarity';
            app.VerificationStatusLabel = uilabel(app.Panel_5);
            app.VerificationStatusLabel.Text = 'Verification Status';
            app.SelectCertificateLabel_2 = uilabel(app.Panel_5);
            app.SelectCertificateLabel_2.Text = '1. Select Certificate';
            app.VerifyCertificateLabel = uilabel(app.Panel_5);
            app.VerifyCertificateLabel.Text = '2. Verify Certificate';
            app.KPRIET2026001Button = uibutton(app.Panel_5, 'push');
            app.KPRIET2026001Button.Text = 'KPRIET2026-001';
            app.Button = uibutton(app.Panel_5, 'push');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Panel_5, 'push');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.Panel_5, 'push');
            app.Button_3.Text = ' - - -';
            app.SelectExpectedWaterMarkButton = uibutton(app.Panel_5, 'push');
            app.SelectExpectedWaterMarkButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExpectedWaterMarkButtonPushed, true);
            app.SelectExpectedWaterMarkButton.Text = 'Select Expected WaterMark';
            app.Panel_2 = uipanel(app.Panel);
            app.StatusLabel = uilabel(app.Panel_2);
            app.StatusLabel.Text = 'Status';
            app.ReadyLabel = uilabel(app.Panel_2);
            app.ReadyLabel.Text = 'Ready';
            app.ClearAllButton = uibutton(app.Panel_2, 'push');
            app.ClearAllButton.Text = 'Clear All';
            app.CertificateVerificationLabel = uilabel(app.Panel);
            app.CertificateVerificationLabel.Text = '     Certificate Verification';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Title = 'Digital Certificate Authentication System';
            app.Label = uilabel(app.Panel_3);
            app.Label.Text = 'Secure verification using digital watermarking';
            app.Image2 = uiimage(app.Panel_3);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'lock.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end